classdef CableTool_v1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CableToolUIFigure               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ListadeCabosTab                 matlab.ui.container.Tab
        IniciarButton                   matlab.ui.control.Button
        OpesPanel                       matlab.ui.container.Panel
        TiposdecabosemfolhasseparadasCheckBox  matlab.ui.control.CheckBox
        SepararcabosinterreaCheckBox    matlab.ui.control.CheckBox
        GerarlistadeAvisosCheckBox      matlab.ui.control.CheckBox
        MostrarfabricanteemodelodeequipamentosCheckBox  matlab.ui.control.CheckBox
        IgnorarcabosnoidentificadosCheckBox  matlab.ui.control.CheckBox
        TiposdecaboDropDown             matlab.ui.control.DropDown
        TiposdecaboDropDownLabel        matlab.ui.control.Label
        OrdenarpornmerodecaboCheckBox   matlab.ui.control.CheckBox
        FicheiroOriginalPanel           matlab.ui.container.Panel
        FicheiroSelecionadoEditField    matlab.ui.control.EditField
        FicheiroSelecionadoLabel        matlab.ui.control.Label
        EscolherFicheiroButton          matlab.ui.control.Button
        MensagemEditField               matlab.ui.control.EditField
        MensagemEditFieldLabel          matlab.ui.control.Label
        ListadeAlteraesTab              matlab.ui.container.Tab
        ListadeCabosNovaPanel           matlab.ui.container.Panel
        FicheiroSelecionadoEditField_3  matlab.ui.control.EditField
        FicheiroSelecionadoEditField_3Label  matlab.ui.control.Label
        EscolherFicheiroButton_3        matlab.ui.control.Button
        ListadeCabosAntigaPanel         matlab.ui.container.Panel
        FicheiroSelecionadoEditField_2  matlab.ui.control.EditField
        FicheiroSelecionadoEditField_2Label  matlab.ui.control.Label
        EscolherFicheiroButton_2        matlab.ui.control.Button
        MensagemEditField_2             matlab.ui.control.EditField
        MensagemEditField_2Label        matlab.ui.control.Label
        GerarListadeAlteraesButton      matlab.ui.control.Button
        ChangelogTab                    matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
    end


    properties (Access = private)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EscolherFicheiroButton
        function EscolherFicheiroButtonPushed(app, event)
            [imported_file,imported_path] = uigetfile('*.xlsx');  %open a xlsx file
            imported_excel = [imported_path imported_file];
            app.FicheiroSelecionadoEditField.Value = imported_excel;
            app.MensagemEditField.Value = 'Por favor aguarde...';
            pause(5)
            opts = detectImportOptions(imported_excel);
            %opts.VariableNamingRule = 'preserve';
            excel_preview = readtable(imported_excel,opts);
            try
                cable_type_preview = unique(excel_preview.Description_1);
                app.TiposdecaboDropDown.Items = {'Todos','Áudio e Vídeo','Áudio, Vídeo e Controlo','Áudio e Controlo','Vídeo e Controlo',cable_type_preview{:}}';
            catch ME
                report = getReport(ME);
                uialert(app.CableToolUIFigure,report,'Error Message');
            end
            app.OrdenarpornmerodecaboCheckBox.Enable = 'on';
            app.IgnorarcabosnoidentificadosCheckBox.Enable = 'on';
            app.MostrarfabricanteemodelodeequipamentosCheckBox.Enable = 'on';
            app.GerarlistadeAvisosCheckBox.Enable = 'on';
            app.TiposdecaboDropDown.Enable = 'on';
            app.TiposdecaboDropDownLabel.Enable = 'on';
            app.IniciarButton.Enable = 'on';
            app.SepararcabosinterreaCheckBox.Enable = 'on';
            app.TiposdecabosemfolhasseparadasCheckBox.Enable = 'on';
            app.MensagemEditField.Value = 'Selecione as opções necessárias e clique em "Iniciar".';
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            %INPUTS
            secondary_cable_number = 1;
            
            %START
            imported_excel = app.FicheiroSelecionadoEditField.Value;
            if strcmp(imported_excel,'Nenhum ficheiro selecionado.')
            else
                opts = detectImportOptions(imported_excel);
                opts = setvartype(opts, 'char');
                %opts.VariableNamingRule = 'preserve';
                excel_in = readtable(imported_excel,opts);

                if ~strcmp(app.TiposdecaboDropDown.Value,'Todos')
                    if strcmp(app.TiposdecaboDropDown.Value,'Áudio e Vídeo')
                        excel_in = excel_in(~(~strcmp(excel_in.Description_1,'Áudio')) | ~(~strcmp(excel_in.Description_1,'SDV')),:);
                    else
                        if strcmp(app.TiposdecaboDropDown.Value,'Áudio, Vídeo e Controlo')
                            excel_in = excel_in(~(~strcmp(excel_in.Description_1,'Áudio')) | ~(~strcmp(excel_in.Description_1,'SDV')) | ~(~strcmp(excel_in.Description_1,'Controlo')),:);
                        else
                            if strcmp(app.TiposdecaboDropDown.Value,'Áudio e Controlo')
                                excel_in = excel_in(~(~strcmp(excel_in.Description_1,'Áudio')) | ~(~strcmp(excel_in.Description_1,'Controlo')),:);
                            else
                                if strcmp(app.TiposdecaboDropDown.Value,'Vídeo e Controlo')
                                    excel_in = excel_in(~(~strcmp(excel_in.Description_1,'SDV')) | ~(~strcmp(excel_in.Description_1,'Controlo')),:);
                                else
                                    excel_in = excel_in(~(~strcmp(excel_in.Description_1,app.TiposdecaboDropDown.Value)),:);
                                end
                            end
                        end
                    end
                end

                if app.IgnorarcabosnoidentificadosCheckBox.Value
                    excel_in = excel_in(~(strcmp(excel_in.StartLabel,"")==1 & strcmp(excel_in.MiddleLabel,"")==1 & strcmp(excel_in.EndLabel,"")==1),:);
                end

                %MODIFICAÇÃO DE CÉLULAS FICA AQUI:
                for i = 1:height(excel_in)
                    L = excel_in.ModelNumber{i};
                    R = excel_in.ModelNumber_2{i};

                    %Krones
                    if contains(L,'Krone','IgnoreCase',true) || contains(R,'Krone','IgnoreCase',true)
                        if contains(R,'Krone','IgnoreCase',true)
                            %excel_in.ModelNumber_2{i} = ['Krone ' excel_in.Name_2{i}]; %Destination Name
                            %Mete os Krones "Dest." como "Source"
                            temp1 = excel_in.Manufacturer{i};
                            temp2 = excel_in.StartPort{i};
                            temp3 = excel_in.Location{i};
                            temp4 = excel_in.Room{i};
                            temp5 = excel_in.Name{i};
                            excel_in.Manufacturer{i} = excel_in.Manufacturer_2{i};
                            excel_in.ModelNumber{i} = R;
                            excel_in.StartPort{i} = excel_in.EndPort{i};
                            excel_in.Location{i} = excel_in.Location_1{i};
                            excel_in.Room{i} = excel_in.Room_1{i};
                            excel_in.Name{i} = excel_in.Name_2{i};
                            excel_in.Manufacturer_2{i} = temp1;
                            excel_in.ModelNumber_2{i} = L;
                            excel_in.EndPort{i} = temp2;
                            excel_in.Location_1{i} = temp3;
                            excel_in.Room_1{i} = temp4;
                            excel_in.Name_2{i} = temp5;
                            L = excel_in.ModelNumber{i};
                            R = excel_in.ModelNumber_2{i};  
                            %excel_in.ModelNumber_2{i} = ['Krone ' excel_in.Name_2{i}]; %Destination Name
                            %excel_in.ModelNumber_2{i} = [excel_in.Manufacturer_2{i} ' ' excel_in.ModelNumber_2{i}];
                        end
                        if contains(L,'Krone','IgnoreCase',true)
                            excel_in.ModelNumber{i} = ['Krone ' excel_in.Name{i}]; %Source Name
                        end
                        if contains(R,'Krone','IgnoreCase',true)
                            excel_in.ModelNumber_2{i} = ['Krone ' excel_in.Name_2{i}]; %Source Name
                        end
                    end
                    
                    %CTPs
                    if contains(L,'CTP','IgnoreCase',true) || contains(R,'CTP','IgnoreCase',true)
                        if contains(L,'CTP','IgnoreCase',true)
                            excel_in.ModelNumber{i} = ['CTP ' excel_in.Name{i}]; %Source Name
                        end
                        if contains(R,'CTP','IgnoreCase',true)
                            excel_in.ModelNumber_2{i} = ['CTP ' excel_in.Name_2{i}]; %Destination Name
                        end
                    end

                    %Paineis
                    if contains(L,'Panel','IgnoreCase',true) || contains(R,'Panel','IgnoreCase',true)
                        if contains(L,'Panel','IgnoreCase',true)
                            %excel_in.ModelNumber{i} = [L ' ' excel_in.Name{i}]; %Source Name
                            excel_in.ModelNumber{i} = ['Painel ' excel_in.Name{i}]; %Source Name
                        end
                        if contains(R,'Panel','IgnoreCase',true)
                            %excel_in.ModelNumber_2{i} = [R ' ' excel_in.Name_2{i}]; %Destination Name
                            excel_in.ModelNumber_2{i} = ['Painel ' excel_in.Name_2{i}]; %Destination Name
                        end
                    end

                    %OUTROS EQUIPAMENTOS
                    if ~contains(L,'CTP','IgnoreCase',true) && ~contains(L,'Krone','IgnoreCase',true) && ~contains(L,'Panel','IgnoreCase',true)
                        %excel_in.ModelNumber{i} = [excel_in.ModelNumber{i} ' - ' excel_in.Name{i} ' - ' excel_in.StartPort{i}];
                        %excel_in.ModelNumber{i} = [excel_in.Name{i} ' - ' excel_in.StartPort{i}];
                        %excel_in.ModelNumber{i} = [excel_in.Manufacturer{i} ' ' excel_in.ModelNumber{i} '(' excel_in.Name{i} ') - ' excel_in.StartPort{i}];
                        if app.MostrarfabricanteemodelodeequipamentosCheckBox.Value
                            excel_in.ModelNumber{i} = [excel_in.Name{i} ' (' excel_in.Manufacturer{i} ' ' excel_in.ModelNumber{i} ')'];
                        else
                            excel_in.ModelNumber{i} = [excel_in.Name{i}];
                        end
                        %cabos.start_connector{i} = excel_in.StartPort{i};
                        excel_in.StartConnector{i} = excel_in.StartPort{i};
                    else
                        %cabos.start_connector{i} = ['-'];
                        excel_in.StartConnector{i} = ['-'];
                    end
                    if ~contains(R,'CTP','IgnoreCase',true) && ~contains(R,'Krone','IgnoreCase',true) && ~contains(R,'Panel','IgnoreCase',true)
                        %excel_in.ModelNumber_2{i} = [excel_in.ModelNumber_2{i} ' - ' excel_in.Name_2{i} ' - ' excel_in.EndPort{i}];
                        %excel_in.ModelNumber_2{i} = [excel_in.Name_2{i} ' - ' excel_in.EndPort{i}];
                        %excel_in.ModelNumber_2{i} = [excel_in.Manufacturer_2{i} ' ' excel_in.ModelNumber_2{i} '(' excel_in.Name_2{i} ') - ' excel_in.EndPort{i}];
                        if app.MostrarfabricanteemodelodeequipamentosCheckBox.Value
                            excel_in.ModelNumber_2{i} = [excel_in.Name_2{i} ' (' excel_in.Manufacturer_2{i} ' ' excel_in.ModelNumber_2{i} ')'];
                        else
                            excel_in.ModelNumber_2{i} = [excel_in.Name_2{i}];
                        end
                        %cabos.end_connector{i} = excel_in.EndPort{i};
                        excel_in.EndConnector{i} = excel_in.EndPort{i};
                    else
                        %cabos.end_connector{i} = ['-'];
                        excel_in.EndConnector{i} = ['-'];
                    end
                end

                %VOLTA A SEPARAR AS COLUNAS EM CAMPOS, MAS AGORA JÁ ESTÁ TUDO FILTRADO
                try
                    manufacturer = excel_in.Manufacturer;               %3
                catch
                    uialert(app.CableToolUIFigure,report,'Error Message');
                end
                try
                    model_number = excel_in.ModelNumber;                %4
                catch
                    uialert(app.CableToolUIFigure,report,'Error Message');
                end
                try
                    location = excel_in.Location;                       %12
                catch
                    uialert(app.CableToolUIFigure,report,'Error Message');
                end
                try
                    room = excel_in.Room;                               %15
                catch
                    uialert(app.CableToolUIFigure,report,'Error Message');
                end
                try
                    end_connector = excel_in.EndConnector;              %26
                    %end_connector = excel_in.EndPort;
                catch
                    uialert(app.CableToolUIFigure,report,'Error Message');
                end
                try
                    start_connector = excel_in.StartConnector;          %33
                    %start_connector = excel_in.StartPort;
                catch
                    uialert(app.CableToolUIFigure,report,'Error Message');
                end
                try
                    type1 = excel_in.Type_1;                            %35
                    description1 = excel_in.Description_1;
                catch
                    uialert(app.CableToolUIFigure,report,'Error Message');
                end
                try
                    manufacturer2 = excel_in.Manufacturer_2;            %37
                catch
                    uialert(app.CableToolUIFigure,report,'Error Message');
                end
                try
                    model_number2 = excel_in.ModelNumber_2;             %38
                catch
                    uialert(app.CableToolUIFigure,report,'Error Message');
                end
                try
                    location1 = excel_in.Location_1;                    %46
                catch
                    uialert(app.CableToolUIFigure,report,'Error Message');
                end
                try
                    room1 = excel_in.Room_1;                            %49
                catch
                    uialert(app.CableToolUIFigure,report,'Error Message');
                end
                try
                    boot_field = excel_in.Boot;
                catch
                    uialert(app.CableToolUIFigure,report,'Error Message');
                end
                %try
                %cable_number = excel_in.CableNumber;                %55
                %if ~app.SecondaryCableNumberCheckBox.Value
                if ~secondary_cable_number
                    cable_number = excel_in.MiddleLabel;%StartLabel instead?
                else
                    cable_number = excel_in.ModelNumber_2;
                    %cable_number = cell(height(excel_in.MiddleLabel),1);
                    %cable_number = char(height(excel_in.MiddleLabel),1);
                    for i = 1:height(excel_in.MiddleLabel)
                        %i;
                        temp1=excel_in.StartLabel{i};
                        temp2=excel_in.MiddleLabel{i};
                        %if 
                        if ~strcmp(temp1,"") %Há StartLabel
                            cable_number{i} = excel_in.StartLabel(i);
                        else
                            if ~strcmp(temp2,"") %Não há StartLabel mas há MiddleLabel
                                cable_number{i} = excel_in.MiddleLabel(i);
                            else
                                cable_number{i} = ''; %Não há nem StartLabel nem MiddleLabel
                            end
                        end
                    end
                end
                cable_number = string(cable_number);
                %catch
                %uialert(app.CableToolUIFigure,report,'Error Message');
                %end

                %EMPTY COLUMN
                temp = size(manufacturer);
                blank_column = NaN(temp(1),1);

                %EXTRA COLUMNS
                %cable_cut = blank_column;
                %cable_run = blank_column;
                %cable_space = blank_column;
                %boot = blank_column;
                %remarks = blank_column;
                
                %Separar cabos e cabos-interÁREA
                cabos_cable_number = [];
                %cabos.cable_cut = [];
                %cabos.cable_run = [];
                cabos.description1 = [];
                %cabos.cable_space = [];
                %cabos.boot = [];
                cabos.manufacturer = [];
                cabos.model_number = [];
                cabos.start_connector = [];
                cabos.location = [];
                cabos.room = [];
                cabos.manufacturer2 = [];
                cabos.model_number2 = [];
                cabos.end_connector = [];
                cabos.location1 = [];
                cabos.room1 = [];
                %cabos.remarks = [];
                cabos.boot = [];

                inter_cable_number = [];
                %inter.cable_cut = [];
                %inter.cable_run = [];
                inter.description1 = [];
                %inter.cable_space = [];
                %inter.boot = [];
                inter.manufacturer = [];
                inter.model_number = [];
                inter.start_connector = [];
                inter.location = [];
                inter.room = [];
                inter.manufacturer2 = [];
                inter.model_number2 = [];
                inter.end_connector = [];
                inter.location1 = [];
                inter.room1 = [];
                inter.boot = [];
                %inter.remarks = [];

                for i = 1:height(cable_number)
                    if sum(isletter(cable_number(i)))
                        %Há letras
                        inter_cable_number = [inter_cable_number;cable_number(i)];
                        inter.description1 = [inter.description1;description1(i)];
                        inter.model_number = [inter.model_number;model_number(i)];
                        inter.start_connector = [inter.start_connector;start_connector(i)];
                        inter.location = [inter.location;location(i)];
                        inter.room = [inter.room;room(i)];
                        inter.model_number2 = [inter.model_number2;model_number2(i)];
                        inter.end_connector = [inter.end_connector;end_connector(i)];
                        inter.location1 = [inter.location1;location1(i)];
                        inter.room1 = [inter.room1;room1(i)];
                        inter.boot = [inter.boot;boot_field(i)];
                    else
                        %Não há letras
                        %excel_out_cabos = [excel_out_cabos; excel_out{i}];
                        cabos_cable_number = [cabos_cable_number;cable_number(i)];
                        cabos.description1 = [cabos.description1;description1(i)];
                        cabos.model_number = [cabos.model_number;model_number(i)];
                        cabos.start_connector = [cabos.start_connector;start_connector(i)];
                        cabos.location = [cabos.location;location(i)];
                        cabos.room = [cabos.room;room(i)];
                        cabos.model_number2 = [cabos.model_number2;model_number2(i)];
                        cabos.end_connector = [cabos.end_connector;end_connector(i)];
                        cabos.location1 = [cabos.location1;location1(i)];
                        cabos.room1 = [cabos.room1;room1(i)];
                        cabos.boot = [cabos.boot;boot_field(i)];
                    end
                end

%                 excel_out = table(cable_number,cable_cut,cable_run,description1,...
%                     cable_space,boot,...
%                     model_number,start_connector,location,room,...
%                     model_number2,end_connector,location1,room1,...
%                     remarks, 'VariableNames', {'Cable No.','Cut','Run','Type',' ','Boot',...
%                     'Source Equipment','Source Conn','Source POS','Source Area',...
%                     'Destination Equipment','Destination Conn','Destination POS','Destination Area',...
%                     'Remarks'});
                temp = size(cabos_cable_number);
                cabos_blank = NaN(temp(1),1);
                cabos_cable_number_backup = cabos_cable_number;
                excel_out_cabos = table(cabos_cable_number,cabos_blank,cabos_blank,cabos.description1,...
                    cabos_blank,cabos.boot,...
                    cabos.model_number,cabos.start_connector,cabos.location,cabos.room,...
                    cabos.model_number2,cabos.end_connector,cabos.location1,cabos.room1,...
                    cabos_blank, cabos_cable_number_backup);
                temp = size(inter_cable_number);
                inter_blank = NaN(temp(1),1);
                inter_cable_number_backup = inter_cable_number;
                excel_out_inter = table(inter_cable_number,inter_blank,inter_blank,inter.description1,...
                    inter_blank,inter.boot,...
                    inter.model_number,inter.start_connector,inter.location,inter.room,...
                    inter.model_number2,inter.end_connector,inter.location1,inter.room1,...
                    inter_blank, inter_cable_number_backup);

                %ORDENAR POR Nº DE CABO
                if app.OrdenarpornmerodecaboCheckBox.Value
                    excel_out_cabos.cabos_cable_number = str2double(excel_out_cabos.cabos_cable_number);%<-
                    %excel_out_cabos = sortrows(excel_out_cabos,1,'ascend');
                    excel_out_cabos = natsortrows(excel_out_cabos);
                    %excel_out_inter = sortrows(excel_out_inter,1,'ascend');
                    excel_out_inter = natsortrows(excel_out_inter);
                end
                
                %FAZ COM QUE OS NÚMEROS DOS CABOS FIQUEM COMO ESTÃO NO
                %ESQUEMA (COM ZEROS À ESQUERDA, ETC.)
                excel_out_cabos = [excel_out_cabos(:,16) excel_out_cabos(:,[2:15])];
                excel_out_inter = [excel_out_inter(:,16) excel_out_inter(:,[2:15])];

                %RENAME TABLE HEADERS TO SOMETHING MORE PRESENTABLE
                excel_out_cabos.Properties.VariableNames{1}  = 'Cable No.';
                excel_out_cabos.Properties.VariableNames{2}  = 'Cut';
                excel_out_cabos.Properties.VariableNames{3}  = 'Run';
                excel_out_cabos.Properties.VariableNames{4}  = 'Type';
                excel_out_cabos.Properties.VariableNames{5}  = ' ';
                excel_out_cabos.Properties.VariableNames{6}  = 'Boot';
                excel_out_cabos.Properties.VariableNames{7}  = 'Source Equipment';
                excel_out_cabos.Properties.VariableNames{8}  = 'Source Conn';
                excel_out_cabos.Properties.VariableNames{9}  = 'Source POS';
                excel_out_cabos.Properties.VariableNames{10} = 'Source Area';
                excel_out_cabos.Properties.VariableNames{11} = 'Destination Equipment';
                excel_out_cabos.Properties.VariableNames{12} = 'Destination Conn';
                excel_out_cabos.Properties.VariableNames{13} = 'Destination POS';
                excel_out_cabos.Properties.VariableNames{14} = 'Destination Area';
                excel_out_cabos.Properties.VariableNames{15} = 'Remarks             ';
                
                excel_out_inter.Properties.VariableNames{1}  = 'Cable No.';
                excel_out_inter.Properties.VariableNames{2}  = 'Cut';
                excel_out_inter.Properties.VariableNames{3}  = 'Run';
                excel_out_inter.Properties.VariableNames{4}  = 'Type';
                excel_out_inter.Properties.VariableNames{5}  = ' ';
                excel_out_inter.Properties.VariableNames{6}  = 'Boot';
                excel_out_inter.Properties.VariableNames{7}  = 'Source Equipment';
                excel_out_inter.Properties.VariableNames{8}  = 'Source Conn';
                excel_out_inter.Properties.VariableNames{9}  = 'Source POS';
                excel_out_inter.Properties.VariableNames{10} = 'Source Area';
                excel_out_inter.Properties.VariableNames{11} = 'Destination Equipment';
                excel_out_inter.Properties.VariableNames{12} = 'Destination Conn';
                excel_out_inter.Properties.VariableNames{13} = 'Destination POS';
                excel_out_inter.Properties.VariableNames{14} = 'Destination Area';
                excel_out_inter.Properties.VariableNames{15} = 'Remarks             ';
                
                %GERA LISTA DE AVISOS
                excel_out_avisos = [];
                if app.GerarlistadeAvisosCheckBox.Value
                    
                    %ENTRADA DO CABO NO AR - CABO
                    for i = 1:height(excel_out_cabos)
                        if strcmp(excel_out_cabos(i,7),excel_out_cabos(i,11)) && strcmp(excel_out_cabos(i,8),excel_out_cabos(i,12)) %If same in-out equipment and connectors
                            excel_out_avisos = [excel_out_avisos; excel_out_cabos(i,:)];
                        end
                    end
                    
                    %SAÍDA DO CABO NO AR - CABO
                    
                    %ENTRADA DO CABO NO AR - INTER-ÁREA
                    for i = 1:height(excel_out_inter)
                        if strcmp(excel_out_inter(i,7),excel_out_inter(i,11)) && strcmp(excel_out_inter(i,8),excel_out_inter(i,12)) %If same in-out equipment and connectors
                            excel_out_avisos = [excel_out_avisos; excel_out_inter(i,:)];
                        end
                    end
                    
                    %SAÍDA DO CABO NO AR - INTER-ÁREA
                    
                    %CABLE NO.s REPETIDOS - CABO
                    [uniqueTableRows1,indexToUniqueRows1,indexBackFromUnique1] = unique(excel_out_cabos);
                    
                    %CABLE NO.s REPETIDOS - INTER-ÁREA
                    [uniqueTableRows2,indexToUniqueRows2,indexBackFromUnique2] = unique(excel_out_inter);
                end

                %GERA O EXCEL DE OUTPUT
                filter = {'*.xlsx';'*.xls'};
                [file, path] = uiputfile(filter);
                excel_out_name = file;
                if isequal(file,0) || isequal(path,0)
                    %disp('User clicked Cancel.')
                else
                    if app.TiposdecabosemfolhasseparadasCheckBox.Value
                        tipos_output = unique(excel_out_cabos.Type)';
                        tipos_howmany = length(tipos_output);
                        tipos_howmany = [1:tipos_howmany];
                        for i = 1:tipos_howmany(end)
                            idx = strcmp(excel_out_cabos.Type,tipos_output{i});
                            temp = excel_out_cabos(idx,:);
                            writetable(temp,[path file],'Sheet',['Cabos ',tipos_output{i}]);
                        end
                    else
                        %JUNTA CABOS E CABOS INTER-ÁREA (SE O USER ESCOLHER)
                        if app.SepararcabosinterreaCheckBox.Value
                            writetable(excel_out_cabos,[path file],'Sheet',['Cabos ',app.TiposdecaboDropDown.Value]);
                            writetable(excel_out_inter,[path file],'Sheet','Cabos Inter-área');
                        else
                            excel_out_both = join(excel_out_cabos,excel_out_inter);
                            writetable(excel_out_both,[path file],'Sheet',['Cabos ',app.TiposdecaboDropDown.Value]);
                        end
                    end
                    if app.SepararcabosinterreaCheckBox.Value
                        writetable(excel_out_inter,[path file],'Sheet','Cabos Inter-área');
                    else
                        excel_out_both = join(excel_out_cabos,excel_out_inter);
                        writetable(excel_out_both,[path file],'Sheet',['Cabos ',app.TiposdecaboDropDown.Value]);
                    end

                    if app.GerarlistadeAvisosCheckBox.Value && ~isempty(excel_out_avisos)
                        writetable(excel_out_avisos,[path file],'Sheet','Cabos A Rever');
                    end
                end        

                %SHUTDOWN
                app.MensagemEditField.Value = [excel_out_name,' criado com sucesso!'];
            end

            %drawing = excel_in.Drawing;                         %1
            %featherID = excel_in.FeatherID;                     %2
            %manufacturer = excel_in.Manufacturer;               %3
            %model_number = excel_in.ModelNumber;                %4
            %description = excel_in.Description;                 %5
            %cost_price = excel_in.CostPrice;                    %6
            %description1 = excel_in.Description_1;              %7
            %design_time = excel_in.DesignTime;                  %8
            %funcao = excel_in.Function;                         %9
            %id = excel_in.ID;                                   %10
            %installation_time = excel_in.InstallationTime;      %11
            %location = excel_in.Location;                       %12
            %management_time = excel_in.ManagementTime;          %13
            %nome = excel_in.Name;                               %14
            %room = excel_in.Room;                               %15
            %sale_price = excel_in.SalePrice;                    %16
            %system = excel_in.System;                           %17
            %type = excel_in.Type;                               %18
            %zone = excel_in.Zone;                               %19
            %start_port = excel_in.StartPort;                    %20
            %start_label = excel_in.StartLabel;                  %21
            %middle_label = excel_in.MiddleLabel;                %22
            %end_label = excel_in.EndLabel;                      %23
            %end_port = excel_in.EndPort;                        %24
            %description2 = excel_in.Description_2;              %25
            %end_connector = excel_in.EndConnector;              %26
            %from = excel_in.From;                               %27
            %length = excel_in.Length;                           %28
            %manufacturer1 = excel_in.Manufacturer_1;            %29
            %model_number1 = excel_in.ModelNumber_1;             %30
            %name1 = excel_in.Name_1;                            %31
            %signal = excel_in.Signal;                           %32
            %start_connector = excel_in.StartConnector;          %33
            %to = excel_in.To;                                   %34
            %type1 = excel_in.Type_1;                            %35
            %via = excel_in.Via;                                 %36
            %manufacturer2 = excel_in.Manufacturer_2;            %37
            %model_number2 = excel_in.ModelNumber_2;             %38
            %description3 = excel_in.Description_3;              %39
            %cost_price1 = excel_in.CostPrice_1;                 %40
            %description4 = excel_in.Description_4;              %41
            %design_time1 = excel_in.DesignTime_1;               %42
            %function1 = excel_in.Function_1;                    %43
            %id1 = excel_in.ID_1;                                %44
            %installation_time1 = excel_in.InstallationTime_1;   %45
            %location1 = excel_in.Location_1;                    %46
            %management_time1 = excel_in.ManagementTime_1;       %47
            %name2 = excel_in.Name_2;                            %48
            %room1 = excel_in.Room_1;                            %49
            %sale_price1 = excel_in.SalePrice_1;                 %50
            %system1 = excel_in.System_1;                        %51
            %type2 = excel_in.Type_2;                            %52
            %zone1 = excel_in.Zone_1;                            %53
            %drawing1 = excel_in.Drawing_1;                      %54
            %(cable_number = excel_in.CableNumber;               %55)
            %(cable_number = excel_in.MiddleLabel;               %55)
        end

        % Button pushed function: EscolherFicheiroButton_2
        function EscolherFicheiroButton_2Pushed(app, event)
            [imported_file,imported_path] = uigetfile('*.xlsx');  %open a xlsx file
            imported_old = [imported_path imported_file];
            app.FicheiroSelecionadoEditField_2.Value = imported_old;
            if ~isempty(app.FicheiroSelecionadoEditField_2.Value) && ~isempty(app.FicheiroSelecionadoEditField_3.Value)
                app.GerarListadeAlteraesButton.Enable = 'on';
            end
        end

        % Button pushed function: EscolherFicheiroButton_3
        function EscolherFicheiroButton_3Pushed(app, event)
            [imported_file,imported_path] = uigetfile('*.xlsx');  %open a xlsx file
            imported_new = [imported_path imported_file];
            app.FicheiroSelecionadoEditField_3.Value = imported_new;
            if ~isempty(app.FicheiroSelecionadoEditField_2.Value) && ~isempty(app.FicheiroSelecionadoEditField_3.Value)
                app.GerarListadeAlteraesButton.Enable = 'on';
            end
        end

        % Button pushed function: GerarListadeAlteraesButton
        function GerarListadeAlteraesButtonPushed(app, event)
            app.MensagemEditField_2.Value = 'Por favor aguarde...';
            
            filename_original = app.FicheiroSelecionadoEditField_2.Value;
            filename_changed = app.FicheiroSelecionadoEditField_3.Value;

            sheet_original=sheetnames(filename_original);
            all_original=[];
            for k=1:numel(sheet_original)
                sheetO = readtable(filename_original,'Sheet',sheet_original{k});
                %sheetO = fillmissing(sheetO,'constant',9999,'DataVariables',@isnumeric);
                all_original = [all_original; sheetO];
            end
            sheet_changed=sheetnames(filename_changed);
            all_changed=[];
            for k=1:numel(sheet_changed)
                sheetC = readtable(filename_changed,'Sheet',sheet_changed{k});
                all_changed = [all_changed; sheetC];
            end
            T1 = all_original;
            T2 = all_changed;
            
            %T1 = readtable(filename_original);
            %T2 = readtable(filename_changed);

            T1 = fillmissing(T1,'constant',9999,'DataVariables',@isnumeric);
            T2 = fillmissing(T2,'constant',9999,'DataVariables',@isnumeric);
            t2t1 = setdiff(T2,T1); %O que está diferente no T2 face ao T1
            t1t2 = setdiff(T1,T2); %O que está diferente no T1 face ao T2
            t_combined = [t2t1;t1t2]; %Combine t2t1 and t1t2

            %Checks if there are more than 1 instance of each cable per list
            if height(unique(T1.CableNo_)) ~= height(T1)
                %app.MensagemEditField_2.Value = ['ERRO: height(unique(T1.CableNo_)) ~= height(T1)'];
                %error('ERRO: ');
            end
            if height(unique(T2.CableNo_)) ~= height(T2)
                %app.MensagemEditField_2.Value = ['ERRO: height(unique(T2.CableNo_)) ~= height(T2)'];
                %error('ERRO: ');
            end

            %Sort
            t_combined = sortrows(t_combined,1,'descend');

            %Separates each of the changed cables between "to add", "to remove" or
            %"changed"
            cabos_para_tirar = [];
            cabos_para_acrescentar = [];
            cabos_alterados = [];
            i=1;
            while(i<=height(t_combined))
                temp2 = ismember(t_combined.CableNo_{i},t2t1.CableNo_);
                temp1 = ismember(t_combined.CableNo_{i},t1t2.CableNo_);
                if temp2 && ~temp1
                    %Acrescentar
                    cabos_para_acrescentar = [cabos_para_acrescentar;t_combined(i,:)];
                    i = i+1;
                else
                    if temp1 && ~temp2
                        %Tirar
                        cabos_para_tirar = [cabos_para_tirar; t_combined(i,:)];
                        i = i+1;
                    else
                        %Alterado
                        cabos_alterados = [cabos_alterados; t_combined(i,:)];
                        i = i+2;
                    end
                end
            end

            %Replaces 9999's back into NaN's
            cabos_para_acrescentar = standardizeMissing(cabos_para_acrescentar, 9999);
            cabos_para_tirar = standardizeMissing(cabos_para_tirar, 9999);
            cabos_alterados = standardizeMissing(cabos_alterados, 9999);

            %RESULTADOS
            %cabos_para_tirar;
            %cabos_para_acrescentar;
            %cabos_alterados;

            %IF NO CHANGES, WE NEED AN EMPTY SHEET
            empty_table = cell2table(cell(0,15));
            if isempty(cabos_para_acrescentar) %If no changes, force to be empty table
                cabos_para_acrescentar = empty_table;
            end
            if isempty(cabos_para_tirar) %If no changes, force to be empty table
                cabos_para_tirar = empty_table;
            end
            if isempty(cabos_alterados) %If no changes, force to be empty table
                cabos_alterados = empty_table;
            end

            %ADJUST COLUMN NAMES
            cabos_para_tirar.Properties.VariableNames{1} = 'Cable No.';
            cabos_para_tirar.Properties.VariableNames{2} = 'Cut';
            cabos_para_tirar.Properties.VariableNames{3} = 'Run';
            cabos_para_tirar.Properties.VariableNames{4} = 'Type';
            cabos_para_tirar.Properties.VariableNames{5} = ' ';
            cabos_para_tirar.Properties.VariableNames{6} = 'Boot';
            cabos_para_tirar.Properties.VariableNames{7} = 'Source Equipment';
            cabos_para_tirar.Properties.VariableNames{8} = 'Source Conn';
            cabos_para_tirar.Properties.VariableNames{9} = 'Source POS';
            cabos_para_tirar.Properties.VariableNames{10} = 'Source Area';
            cabos_para_tirar.Properties.VariableNames{11} = 'Destination Equipment';
            cabos_para_tirar.Properties.VariableNames{12} = 'Destination Conn';
            cabos_para_tirar.Properties.VariableNames{13} = 'Destination POS';
            cabos_para_tirar.Properties.VariableNames{14} = 'Destination Area';
            cabos_para_tirar.Properties.VariableNames{15} = 'Remarks';

            cabos_para_acrescentar.Properties.VariableNames{1} = 'Cable No.';
            cabos_para_acrescentar.Properties.VariableNames{2} = 'Cut';
            cabos_para_acrescentar.Properties.VariableNames{3} = 'Run';
            cabos_para_acrescentar.Properties.VariableNames{4} = 'Type';
            cabos_para_acrescentar.Properties.VariableNames{5} = ' ';
            cabos_para_acrescentar.Properties.VariableNames{6} = 'Boot';
            cabos_para_acrescentar.Properties.VariableNames{7} = 'Source Equipment';
            cabos_para_acrescentar.Properties.VariableNames{8} = 'Source Conn';
            cabos_para_acrescentar.Properties.VariableNames{9} = 'Source POS';
            cabos_para_acrescentar.Properties.VariableNames{10} = 'Source Area';
            cabos_para_acrescentar.Properties.VariableNames{11} = 'Destination Equipment';
            cabos_para_acrescentar.Properties.VariableNames{12} = 'Destination Conn';
            cabos_para_acrescentar.Properties.VariableNames{13} = 'Destination POS';
            cabos_para_acrescentar.Properties.VariableNames{14} = 'Destination Area';
            cabos_para_acrescentar.Properties.VariableNames{15} = 'Remarks';

            cabos_alterados.Properties.VariableNames{1} = 'Cable No.';
            cabos_alterados.Properties.VariableNames{2} = 'Cut';
            cabos_alterados.Properties.VariableNames{3} = 'Run';
            cabos_alterados.Properties.VariableNames{4} = 'Type';
            cabos_alterados.Properties.VariableNames{5} = ' ';
            cabos_alterados.Properties.VariableNames{6} = 'Boot';
            cabos_alterados.Properties.VariableNames{7} = 'Source Equipment';
            cabos_alterados.Properties.VariableNames{8} = 'Source Conn';
            cabos_alterados.Properties.VariableNames{9} = 'Source POS';
            cabos_alterados.Properties.VariableNames{10} = 'Source Area';
            cabos_alterados.Properties.VariableNames{11} = 'Destination Equipment';
            cabos_alterados.Properties.VariableNames{12} = 'Destination Conn';
            cabos_alterados.Properties.VariableNames{13} = 'Destination POS';
            cabos_alterados.Properties.VariableNames{14} = 'Destination Area';
            cabos_alterados.Properties.VariableNames{15} = 'Remarks';

            %ORDENA OS CABOS ALTERADOS
            cabos_para_acrescentar = natsortrows(cabos_para_acrescentar);
            cabos_para_tirar = natsortrows(cabos_para_tirar);
            cabos_alterados = natsortrows(cabos_alterados);
            
            %GERA O EXCEL DE OUTPUT
            filter = {'*.xlsx';'*.xls'};
            [file, path] = uiputfile(filter);
            excel_out_name = file;
            if isequal(file,0) || isequal(path,0)
                %disp('User clicked Cancel.')
            else
                writetable(cabos_para_acrescentar,[path file],'Sheet','Acrescentar');
                writetable(cabos_para_tirar,[path file],'Sheet','Tirar');
                writetable(cabos_alterados,[path file],'Sheet','Alterar');
            end

            %DONE!
            app.MensagemEditField_2.Value = [excel_out_name,' criado com sucesso!'];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CableToolUIFigure = uifigure('Visible', 'off');
            app.CableToolUIFigure.Name = 'CableTool';
            app.CableToolUIFigure.Icon = 'wire.png';
            app.TabGroup = uitabgroup(app.CableToolUIFigure);
            app.ListadeCabosTab = uitab(app.TabGroup);
            app.ListadeCabosTab.Title = 'Lista de Cabos';
            app.MensagemEditFieldLabel = uilabel(app.ListadeCabosTab);
            app.MensagemEditFieldLabel.Text = 'Mensagem';
            app.MensagemEditField = uieditfield(app.ListadeCabosTab, 'text');
            app.MensagemEditField.Editable = 'off';
            app.MensagemEditField.Value = 'Escolha o ficheiro original através do botão "Escolher Ficheiro".';
            app.FicheiroOriginalPanel = uipanel(app.ListadeCabosTab);
            app.FicheiroOriginalPanel.Title = 'Ficheiro Original';
            app.EscolherFicheiroButton = uibutton(app.FicheiroOriginalPanel, 'push');
            app.EscolherFicheiroButton.ButtonPushedFcn = createCallbackFcn(app, @EscolherFicheiroButtonPushed, true);
            app.EscolherFicheiroButton.Text = {'Escolher'; 'Ficheiro'};
            app.FicheiroSelecionadoLabel = uilabel(app.FicheiroOriginalPanel);
            app.FicheiroSelecionadoLabel.Text = {'Ficheiro'; 'Selecionado'};
            app.FicheiroSelecionadoEditField = uieditfield(app.FicheiroOriginalPanel, 'text');
            app.FicheiroSelecionadoEditField.Editable = 'off';
            app.FicheiroSelecionadoEditField.Value = 'Nenhum ficheiro selecionado.';
            app.OpesPanel = uipanel(app.ListadeCabosTab);
            app.OpesPanel.Title = 'Opções';
            app.OrdenarpornmerodecaboCheckBox = uicheckbox(app.OpesPanel);
            app.OrdenarpornmerodecaboCheckBox.Enable = 'off';
            app.OrdenarpornmerodecaboCheckBox.Text = 'Ordenar por número de cabo';
            app.OrdenarpornmerodecaboCheckBox.Value = true;
            app.TiposdecaboDropDownLabel = uilabel(app.OpesPanel);
            app.TiposdecaboDropDownLabel.Enable = 'off';
            app.TiposdecaboDropDownLabel.Text = 'Tipos de cabo';
            app.TiposdecaboDropDown = uidropdown(app.OpesPanel);
            app.TiposdecaboDropDown.Items = {' ', ''};
            app.TiposdecaboDropDown.Enable = 'off';
            app.TiposdecaboDropDown.Value = ' ';
            app.IgnorarcabosnoidentificadosCheckBox = uicheckbox(app.OpesPanel);
            app.IgnorarcabosnoidentificadosCheckBox.Enable = 'off';
            app.IgnorarcabosnoidentificadosCheckBox.Text = 'Ignorar cabos não identificados';
            app.IgnorarcabosnoidentificadosCheckBox.Value = true;
            app.MostrarfabricanteemodelodeequipamentosCheckBox = uicheckbox(app.OpesPanel);
            app.MostrarfabricanteemodelodeequipamentosCheckBox.Enable = 'off';
            app.MostrarfabricanteemodelodeequipamentosCheckBox.Text = {'Mostrar fabricante e modelo'; 'de equipamentos'};
            app.GerarlistadeAvisosCheckBox = uicheckbox(app.OpesPanel);
            app.GerarlistadeAvisosCheckBox.Enable = 'off';
            app.GerarlistadeAvisosCheckBox.Text = 'Gerar lista de Avisos';
            app.GerarlistadeAvisosCheckBox.Value = true;
            app.SepararcabosinterreaCheckBox = uicheckbox(app.OpesPanel);
            app.SepararcabosinterreaCheckBox.Enable = 'off';
            app.SepararcabosinterreaCheckBox.Text = 'Separar cabos/inter-área';
            app.SepararcabosinterreaCheckBox.Value = true;
            app.TiposdecabosemfolhasseparadasCheckBox = uicheckbox(app.OpesPanel);
            app.TiposdecabosemfolhasseparadasCheckBox.Enable = 'off';
            app.TiposdecabosemfolhasseparadasCheckBox.Text = 'Tipos de cabos em folhas separadas';
            app.TiposdecabosemfolhasseparadasCheckBox.Value = true;
            app.IniciarButton = uibutton(app.ListadeCabosTab, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Enable = 'off';
            app.IniciarButton.Text = 'Iniciar';
            app.ListadeAlteraesTab = uitab(app.TabGroup);
            app.ListadeAlteraesTab.Title = 'Lista de Alterações';
            app.GerarListadeAlteraesButton = uibutton(app.ListadeAlteraesTab, 'push');
            app.GerarListadeAlteraesButton.ButtonPushedFcn = createCallbackFcn(app, @GerarListadeAlteraesButtonPushed, true);
            app.GerarListadeAlteraesButton.Enable = 'off';
            app.GerarListadeAlteraesButton.Text = 'Gerar Lista de Alterações';
            app.MensagemEditField_2Label = uilabel(app.ListadeAlteraesTab);
            app.MensagemEditField_2Label.Text = 'Mensagem';
            app.MensagemEditField_2 = uieditfield(app.ListadeAlteraesTab, 'text');
            app.MensagemEditField_2.Editable = 'off';
            app.MensagemEditField_2.Value = 'Escolha as listas antiga e nova, e clique em "Gerar Lista de Alterações".';
            app.ListadeCabosAntigaPanel = uipanel(app.ListadeAlteraesTab);
            app.ListadeCabosAntigaPanel.Title = 'Lista de Cabos Antiga';
            app.EscolherFicheiroButton_2 = uibutton(app.ListadeCabosAntigaPanel, 'push');
            app.EscolherFicheiroButton_2.ButtonPushedFcn = createCallbackFcn(app, @EscolherFicheiroButton_2Pushed, true);
            app.EscolherFicheiroButton_2.Text = {'Escolher'; 'Ficheiro'};
            app.FicheiroSelecionadoEditField_2Label = uilabel(app.ListadeCabosAntigaPanel);
            app.FicheiroSelecionadoEditField_2Label.Text = {'Ficheiro'; 'Selecionado'};
            app.FicheiroSelecionadoEditField_2 = uieditfield(app.ListadeCabosAntigaPanel, 'text');
            app.FicheiroSelecionadoEditField_2.Editable = 'off';
            app.ListadeCabosNovaPanel = uipanel(app.ListadeAlteraesTab);
            app.ListadeCabosNovaPanel.Title = 'Lista de Cabos Nova';
            app.EscolherFicheiroButton_3 = uibutton(app.ListadeCabosNovaPanel, 'push');
            app.EscolherFicheiroButton_3.ButtonPushedFcn = createCallbackFcn(app, @EscolherFicheiroButton_3Pushed, true);
            app.EscolherFicheiroButton_3.Text = {'Escolher'; 'Ficheiro'};
            app.FicheiroSelecionadoEditField_3Label = uilabel(app.ListadeCabosNovaPanel);
            app.FicheiroSelecionadoEditField_3Label.Text = {'Ficheiro'; 'Selecionado'};
            app.FicheiroSelecionadoEditField_3 = uieditfield(app.ListadeCabosNovaPanel, 'text');
            app.FicheiroSelecionadoEditField_3.Editable = 'off';
            app.ChangelogTab = uitab(app.TabGroup);
            app.ChangelogTab.Title = 'Changelog';
            app.TextArea = uitextarea(app.ChangelogTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'V1.0:'; 'Full release.'};
            % Show the figure after all components are created
            app.CableToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CableTool_v1_0

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CableToolUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.CableToolUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CableToolUIFigure)
        end
    end
end